classdef monitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ConnectButton            matlab.ui.control.Button
        ControllerDropDown       matlab.ui.control.DropDown
        ControllerDropDownLabel  matlab.ui.control.Label
        RunModeDropDown          matlab.ui.control.DropDown
        RunModeDropDownLabel     matlab.ui.control.Label
        StopButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        LineAxes                 matlab.ui.control.UIAxes
        ErrorAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cfg
        appState
        tuneDB

        net
        runLog
        frameBuf

        ctrlReg struct
        steerCtrlState


        tmr
        t0 uint64 = uint64(0);
        stopFlag logical = true;

        frameIdx uint32 = 0
        rxFailCount uint16 = 0
        
        lastSteerDeg double = 0
        lastError double = 0
        
        plotDecim uint16 = 3
        driveDecim uint16 = 10

                
    end


    methods (Access = private)
        
        % Initialize app
        function startup(app)
        
            % ===== Resolve path to mlab/ =====
            appFile = which("monitor");          % path to monitor.mlapp
            appDir  = fileparts(appFile);        % .../mlab/app
            mlabDir = fileparts(appDir);         % .../mlab
        
            addpath(genpath(mlabDir));
            rehash;                              % OK trong App Designer
        
            % ===== Debug (có thể giữ) =====
            disp("monitor.mlapp at: " + string(appFile));
            disp("mlabDir = " + string(mlabDir));
            disp("exist capstone.io.Network = " + string(exist("capstone.io.Network","class")));
        
            % ===== Init app data =====
            app.cfg      = capstone.types.makeCfgDefault();           % hoặc capstone.types nếu bạn đã đổi tên folder
            app.appState = capstone.types.makeAppStateDefault(app.cfg);
            app.tuneDB   = capstone.data.TuneDB_default(app.cfg);
            
            app.frameBuf = capstone.util.frameBufferInit(500);
            app.runLog   = capstone.types.makeRunLog("run_" + string(datetime("now","Format","yyyyMMdd_HHmmss")), app.cfg);
            
            app.steerCtrlState = [];
            app.ctrlReg = capstone.control.ControllerRegistry();
            app.stopFlag = true;
            app.t0 = uint64(0);
        
            % ===== Create Network object =====
            app.net = capstone.io.Network();

            % ===== Minimal cfg (tạm thời) =====
            app.cfg.proto.CMD_MODE_OP   = uint8(hex2dec('FF'));
            app.cfg.proto.CMD_MODE_IDLE = uint8(hex2dec('FE'));
            app.cfg.proto.CMD_CTRL      = uint8(hex2dec('F1'));
            app.cfg.proto.CMD_STOP      = uint8(hex2dec('F0'));
            app.cfg.proto.ACK           = uint8(hex2dec('20'));
            
            app.cfg.timing.rxTimeoutS   = 0.2;
            app.cfg.timing.dtControlS   = 0.05;   % = timer period của bạn
            
            % Line calibration tạm (thay bằng của bạn sau)
            app.cfg.line.offset    = [0 0 0 0 0];
            app.cfg.line.scale     = [1 1 1 1 1];
            app.cfg.line.threshold = 0.5 * ones(1,5);
            app.cfg.line.positions = [-2 -1 0 1 2];
            
            % Steer limits theo firmware
            app.cfg.steer.minDeg   = 55;
            app.cfg.steer.maxDeg   = 105;
            app.cfg.steer.centerDeg= 75;
            
            % Speed (PWM16)
            app.cfg.drive.basePWM16 = uint16(65504);   % giảm tốc để test

        
            disp("[monitor] startup OK");
        end

        % Start timer
        function startTimer(app)
            if isempty(app.tmr) || ~isvalid(app.tmr)
                app.tmr = timer( ...
                    "ExecutionMode","fixedSpacing", ...
                    "Period", 0.05, ...       % 20 Hz giả lập
                    "TimerFcn", @(~,~)app.tick() );
            end
            start(app.tmr);
        end

        % Stop timer
        function stopTimer(app)
            if ~isempty(app.tmr) && isvalid(app.tmr)
                stop(app.tmr);
            end
        end
        
        % Timer interrupt
        function tick(app)
            if app.stopFlag || app.t0 == 0
                return;
            end
            if isempty(app.net) || ~app.net.isConnected()
                return;
            end
        
            app.frameIdx = app.frameIdx + 1;
        
            % ===== 1) ON/OFF steering (tối thiểu) =====
            % Nếu chưa có error (vì chưa recv), dùng lastError
            e = app.lastError;
        
            % Tham số ON/OFF (tạm)
            deadband = 0.20;          % chỉnh sau
            leftDeg  = 60;
            rightDeg = 95;
            center   = app.cfg.steer.centerDeg;
        
            if e > deadband
                steerDeg = rightDeg;
            elseif e < -deadband
                steerDeg = leftDeg;
            else
                steerDeg = center;
            end
        
            % clamp
            steerDeg = max(app.cfg.steer.minDeg, min(app.cfg.steer.maxDeg, steerDeg));
        
            % ===== 2) speed scheduling đơn giản =====
            speedPWM16 = app.cfg.drive.basePWM16;
        
            % ===== 3) SEND control (5B, LE) =====
            disp("TX speed_u16 = " + string(speedPWM16) + ", steer = " + string(steerDeg));
            app.net.sendControl(uint16(speedPWM16), uint16(steerDeg), app.cfg.proto.CMD_CTRL);
        
            % ===== 4) RECV sensor frame (22B) =====
            [ok, meas] = app.net.recvSensorFrame(app.cfg.timing.rxTimeoutS);
            if ~ok
                app.rxFailCount = app.rxFailCount + 1;
                if app.rxFailCount >= 10
                    disp("[tick] RX LOST -> STOP");
                    app.net.sendControl(uint16(0), uint16(center), app.cfg.proto.CMD_STOP);
                    app.stopFlag = true;
                    app.stopTimer();
                end
                return;
            end
            app.rxFailCount = 0;
        
            % ===== 5) PROCESS line -> error =====
            [lineNorm, mask] = capstone.signal.normalizeLine( ...
                meas.lineRaw, app.cfg.line.offset, app.cfg.line.scale, app.cfg.line.threshold);
        
            e = capstone.signal.computeError(lineNorm, mask, app.cfg.line.positions);
            app.lastError = e;
            app.lastSteerDeg = steerDeg;
        
            % ===== 6) PLOT (decimate) =====
            if mod(app.frameIdx, app.plotDecim) == 0
                t = toc(app.t0);
                plot(app.ErrorAxes, t, e, '.b'); hold(app.ErrorAxes,"on");
                bar(app.LineAxes, lineNorm); ylim(app.LineAxes,[0 1]);
                drawnow limitrate;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.startup();
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % sửa thông số theo bạn
            btName = "Behind the scream";
            ch = 1;

    app.net.connectBluetooth(btName, ch);
            disp("[monitor] Connect pressed");
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            disp("[monitor] Start pressed");
            if isempty(app.net) || ~app.net.isConnected()
                disp("[monitor] Not connected"); return;
            end
        
            app.stopFlag = false;
            app.t0 = tic;
        
            app.frameIdx = 0;
            app.rxFailCount = 0;
            app.lastSteerDeg = 0;
            app.lastError = 0;
        
            app.net.sendByte(app.cfg.proto.CMD_MODE_OP);
            app.net.waitAck(app.cfg.timing.rxTimeoutS);
        
            app.startTimer();

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            disp("[monitor] Stop pressed");
            app.stopFlag = true;
            app.stopTimer();
        
            if ~isempty(app.net) && app.net.isConnected()
                app.net.sendControl(uint16(0), uint16(app.cfg.steer.centerDeg), app.cfg.proto.CMD_STOP);
                app.net.sendControl(uint16(0), uint16(app.cfg.steer.centerDeg), uint16(hex2dec('FE'))); % exit op
                app.net.waitAck(1.0);
            end
        
            app.t0 = uint64(0);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ErrorAxes = uiaxes(app.UIFigure);
            title(app.ErrorAxes, 'Error')
            xlabel(app.ErrorAxes, 'X')
            ylabel(app.ErrorAxes, 'Y')
            zlabel(app.ErrorAxes, 'Z')
            app.LineAxes = uiaxes(app.UIFigure);
            title(app.LineAxes, 'Line')
            xlabel(app.LineAxes, 'X')
            ylabel(app.LineAxes, 'Y')
            zlabel(app.LineAxes, 'Z')
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RunModeDropDownLabel = uilabel(app.UIFigure);
            app.RunModeDropDownLabel.Text = 'Run Mode';
            app.RunModeDropDown = uidropdown(app.UIFigure);
            app.RunModeDropDown.Items = {'Auto', 'Manual', 'Stop'};
            app.RunModeDropDown.Value = 'Auto';
            app.ControllerDropDownLabel = uilabel(app.UIFigure);
            app.ControllerDropDownLabel.Text = 'Controller';
            app.ControllerDropDown = uidropdown(app.UIFigure);
            app.ControllerDropDown.Items = {'PID', 'ONOFF'};
            app.ControllerDropDown.Value = 'PID';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = monitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end